classdef admin_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ADMINDASHBOARDButton  matlab.ui.control.Button
        table                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        resp; % Description
        candidates;
        unavailable = 0;
        blocked;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartUp(app)
            app.resp = webread("https://amused-pleat-goat.cyclic.app/interviewer/getAll");
            % app.candidates = webread("https://amused-pleat-goat.cyclic.app/candidate/getAll");

            n_rows = length(app.resp);
            n_cols = 9;
            app.blocked = zeros(n_rows, n_cols);
            

            for i = 1:length(app.resp)
                person = app.resp(i);
                for j = 1:length(person.blockedSlots)
                    slot = person.blockedSlots(j);
                    style = uistyle;
                    style.BackgroundColor = "red";
                    disp(i);
                    disp(slot.start);
                    disp("");
                    % if ~slot
                    %     continue;
                    % end
                    addStyle(app.table,style,"cell",[i, slot.start-8]);
                    app.blocked(i,slot.start-8) = 1;
                end
                for j = 1:length(person.interviewSlots)
                    slot = person.interviewSlots(j);
                    timeSlot = slot.timeSlot;
                    style = uistyle;
                    style.BackgroundColor = "green";
                    addStyle(app.table,style,"cell",[i, timeSlot.start-8]);
                end
            end

            endHour = 18;
            startHour = 9;
            timeStrings = cell(1, endHour - startHour + 1);

            for hour = startHour:endHour
                timeStrings{hour - startHour + 1} = sprintf('%02d:00', hour);
            end
            app.table.ColumnName = timeStrings;

            interviewerNames = cell(1, n_rows);
            for num = 1:n_rows
                interviewer = app.resp(num);
                interviewerNames{num} = num2str(interviewer.name);
            end
            app.table.RowName = interviewerNames;

            dat = cell(n_rows,n_cols);
            app.table.Data = dat;
        end

        % Cell selection callback: table
        function tableCellSelection(app, event)
            indices = event.Indices;
            % disp(indices);
            disp(app.blocked);
            interviewerNumber = indices(1);
            if ~app.blocked(indices(1),indices(2))
                dialogbox(app,app.resp(interviewerNumber),interviewerNumber);
                % disp(indices);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = '';
            app.table.RowName = {};
            app.table.CellSelectionCallback = createCallbackFcn(app, @tableCellSelection, true);
            app.ADMINDASHBOARDButton = uibutton(app.UIFigure, 'push');
            app.ADMINDASHBOARDButton.Text = 'ADMIN DASHBOARD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = admin_page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end