classdef interviewer_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MESSAGESButton              matlab.ui.control.Button
        REJECTButton                matlab.ui.control.Button
        ACCEPTButton                matlab.ui.control.Button
        MESSAGESListBox             matlab.ui.control.ListBox
        SELECTUNAVAILABILITYButton  matlab.ui.control.Button
        interviewer_type            matlab.ui.control.Button
        interviewer_name            matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        table                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        callingApp;
        unavailable = 0;
        resp;
        selectedMessage;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartUp(app, caller, resp)
            app.resp = resp;
            app.interviewer_name.Text = upper(string(app.resp.name));
            app.interviewer_type.Text = upper(string(app.resp.type));
            myCellArray = cell(1, 9);
            for num = 1:9
                myCellArray{num} = num2str(num);
            end
            app.table.RowName = myCellArray;
            dat = cell(9, 2);
            app.table.Data = dat;

            for r=1:9
                app.table.Data{r,1} = sprintf('%d - %d', r+8, r+9);
            end
            for idx = 1:length(app.resp.blockedSlots)
                    slot = app.resp.blockedSlots(idx);
                    style = uistyle;
                    style.BackgroundColor = "red";
                    addStyle(app.table,style,"cell",[slot.start-8, 1]);
            end

            displayList = strings(1,length(app.resp.interviewSlots));

            for idx = 1:length(app.resp.interviewSlots)
                    slot = app.resp.interviewSlots(idx);
                    if app.resp.notify>0
                        displayList(idx) = string(slot.timeSlot.start);
                    end
                    timeSlot = slot.timeSlot;
                    style = uistyle;
                    style.BackgroundColor = "green";
                    addStyle(app.table,style,"cell",[timeSlot.start-8, 1]);
            end
            app.MESSAGESListBox.Items = displayList;
        end

        % Button pushed function: SELECTUNAVAILABILITYButton
        function unavailableCallback(app, event)
            if app.unavailable
                app.unavailable = 0;
                app.SELECTUNAVAILABILITYButton.BackgroundColor = [0.18,0.43,0.12];
            else
                app.unavailable = 1;
                app.SELECTUNAVAILABILITYButton.BackgroundColor = "r";
            end
        end

        % Cell selection callback: table
        function tableCellSelection(app, event)
            indices = event.Indices;
            slotNumber = indices(1);
            if app.unavailable
                style = uistyle;
                style.BackgroundColor = "red";
                addStyle(app.table,style,"row",slotNumber);
                timeSlot = struct('start',slotNumber+8,'end',slotNumber+9);
                data = struct('email',app.resp.email,'timeSlot',timeSlot);
                webwrite("https://amused-pleat-goat.cyclic.app/interviewer/makeSlotBusy",data);
            end
        end

        % Clicked callback: MESSAGESListBox
        function messageClicked(app, event)
            item = event.InteractionInformation.Item;
            
            app.selectedMessage = item;
            app.ACCEPTButton.Visible = "on";
            app.REJECTButton.Visible = "on";
        end

        % Button pushed function: ACCEPTButton
        function acceptCallback(app, event)
            t1 = str2double(app.MESSAGESListBox.Value);
            t2 = t1+1;

            slot = struct('start', t1,'end', t2);
            data = struct('email',app.resp.email,'timeSlot',slot);
            webwrite("https://amused-pleat-goat.cyclic.app/interviewer/accept",data);

            arr = app.MESSAGESListBox.Items;
            indicesToRemove = strcmp(arr, valueToRemove);

            arr(indicesToRemove) = [];
            app.MESSAGESListBox.Items = arr;
            app.onStartUp(app.callingApp,app.resp);
        end

        % Button pushed function: REJECTButton
        function rejectCallback(app, event)
            t1 = str2double(app.MESSAGESListBox.Value);
            t2 = t1+1;

            slot = struct('start', t1,'end', t2);
            data = struct('email',app.resp.email,'timeSlot',slot);
            webwrite("https://amused-pleat-goat.cyclic.app/interviewer/reject",data);
            app.onStartUp(app,app.callingApp,app.resp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.table = uitable(app.Panel);
            app.table.ColumnName = {'TIME SLOT'; 'CANDIDATE'};
            app.table.RowName = {};
            app.table.CellSelectionCallback = createCallbackFcn(app, @tableCellSelection, true);
            app.interviewer_name = uilabel(app.UIFigure);
            app.interviewer_name.Text = '';
            app.interviewer_type = uibutton(app.UIFigure, 'push');
            app.interviewer_type.Text = {''; ''};
            app.SELECTUNAVAILABILITYButton = uibutton(app.UIFigure, 'push');
            app.SELECTUNAVAILABILITYButton.ButtonPushedFcn = createCallbackFcn(app, @unavailableCallback, true);
            app.SELECTUNAVAILABILITYButton.Text = 'SELECT UNAVAILABILITY';
            app.MESSAGESListBox = uilistbox(app.UIFigure);
            app.MESSAGESListBox.Items = {''};
            app.MESSAGESListBox.ClickedFcn = createCallbackFcn(app, @messageClicked, true);
            app.MESSAGESListBox.Value = '';
            app.ACCEPTButton = uibutton(app.UIFigure, 'push');
            app.ACCEPTButton.ButtonPushedFcn = createCallbackFcn(app, @acceptCallback, true);
            app.ACCEPTButton.Visible = 'off';
            app.ACCEPTButton.Text = 'ACCEPT';
            app.REJECTButton = uibutton(app.UIFigure, 'push');
            app.REJECTButton.ButtonPushedFcn = createCallbackFcn(app, @rejectCallback, true);
            app.REJECTButton.Visible = 'off';
            app.REJECTButton.Text = 'REJECT';
            app.MESSAGESButton = uibutton(app.UIFigure, 'push');
            app.MESSAGESButton.Text = 'MESSAGES';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interviewer_page(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)onStartUp(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end