classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        LOGINTab               matlab.ui.container.Tab
        login_status           matlab.ui.control.Label
        ENTEREMAILLabel        matlab.ui.control.Label
        ENTEREMAILTextArea     matlab.ui.control.TextArea
        ENTERPASSWORDLabel     matlab.ui.control.Label
        LOGINButton            matlab.ui.control.Button
        ENTERPASSWORDTextArea  matlab.ui.control.TextArea
        INTERVIEWERButton      matlab.ui.control.Button
        ADMINButton            matlab.ui.control.Button
        REGISTERTab            matlab.ui.container.Tab
        display_registered     matlab.ui.control.Label
        SELECTTYPEButtonGroup  matlab.ui.container.ButtonGroup
        HRButton               matlab.ui.control.RadioButton
        MANAGERIALButton       matlab.ui.control.RadioButton
        TECHNICALButton        matlab.ui.control.RadioButton
        EMAILTextArea          matlab.ui.control.TextArea
        EMAILTextAreaLabel     matlab.ui.control.Label
        REGISTERButton         matlab.ui.control.Button
        PASSWORDTextArea       matlab.ui.control.TextArea
        PASSWORDTextAreaLabel  matlab.ui.control.Label
        USERNAMETextArea       matlab.ui.control.TextArea
        USERNAMETextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        admin_mode_ = 0;
        interviewer_mode_ = 0;
        adminPassword = "user";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ADMINButton
        function adminCallback(app, event)
            app.ENTERPASSWORDTextArea.Visible = "on";
            app.ENTERPASSWORDLabel.Visible = "on";
            app.ENTEREMAILTextArea.Visible = "on";
            app.ENTEREMAILLabel.Visible = "on";

            app.LOGINButton.Visible = "on";

            app.ADMINButton.BackgroundColor = [0.82,0.96,0.95];
            app.INTERVIEWERButton.BackgroundColor = [0.94,0.94,0.94];
            app.admin_mode_ = 1;
            app.interviewer_mode_ = 0;
        end

        % Button pushed function: INTERVIEWERButton
        function interviewerCallback(app, event)
            app.ENTERPASSWORDTextArea.Visible = "on";
            app.ENTERPASSWORDLabel.Visible = "on";
            app.ENTEREMAILTextArea.Visible = "on";
            app.ENTEREMAILLabel.Visible = "on";

            app.LOGINButton.Visible = "on";

            app.ADMINButton.BackgroundColor = [0.94,0.94,0.94];
            app.INTERVIEWERButton.BackgroundColor = [0.93,0.85,0.85];
            app.admin_mode_ = 0;
            app.interviewer_mode_ = 1;
        end

        % Button pushed function: LOGINButton
        function loginCallback(app, event)
            if app.admin_mode_
                if app.ENTERPASSWORDTextArea.Value == app.adminPassword
                    admin_page;
                else
                    disp("Invalid password!");
                end
            else
                password = app.ENTERPASSWORDTextArea.Value;
                email = app.ENTEREMAILTextArea.Value;
                data = struct('email',email);
                resp = webwrite("https://amused-pleat-goat.cyclic.app/interviewer/getOne", ...
                    data);
                if isempty(resp)
                    app.login_status.Visible = "on";
                    app.login_status.Text = "INVALID!";
                    app.login_status.FontColor = "r";
                    return;
                end
                if string(cell2mat(password)) == string(resp.password)
                    app.login_status.Visible = "on";
                    app.login_status.Text = "SUCCESSFUL!";
                    app.login_status.FontColor = [0.18,0.43,0.12];
                    interviewer_page(app,resp);
                else
                    app.login_status.Visible = "on";
                    app.login_status.Text = "INVALID!";
                    app.login_status.FontColor = "r";
                end
            end
        end

        % Button pushed function: REGISTERButton
        function registerCallback(app, event)
            email = app.EMAILTextArea.Value;
            password = app.PASSWORDTextArea.Value;
            name = app.USERNAMETextArea.Value; 
            type = app.SELECTTYPEButtonGroup.SelectedObject.Text;

            % Post new user data 
            data = struct('email',email,'password',password,'name',name,'type',type);
            try
                resp = webwrite("https://amused-pleat-goat.cyclic.app/interviewer/create", ...
                    data);
                disp(resp.password);
            catch
                app.display_registered.Visible = "on";
                app.display_registered.Text = "EXISTS!";
                app.display_registered.FontColor = "r";
                return;
            end
       
            if email ~= "" && password ~= "" && name ~= "" && type ~= ""
                app.display_registered.Visible = "on";
                app.display_registered.Text = "REGISTERED!";
                app.display_registered.FontColor = [0.18,0.43,0.12];
            else
                app.display_registered.Visible = "on";
                app.display_registered.Text = "INVALID!";
                app.display_registered.FontColor = "r";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LOGINTab = uitab(app.TabGroup);
            app.LOGINTab.Title = 'LOGIN';
            app.ADMINButton = uibutton(app.LOGINTab, 'push');
            app.ADMINButton.ButtonPushedFcn = createCallbackFcn(app, @adminCallback, true);
            app.ADMINButton.Text = 'ADMIN';
            app.INTERVIEWERButton = uibutton(app.LOGINTab, 'push');
            app.INTERVIEWERButton.ButtonPushedFcn = createCallbackFcn(app, @interviewerCallback, true);
            app.INTERVIEWERButton.Text = 'INTERVIEWER';
            app.ENTERPASSWORDTextArea = uitextarea(app.LOGINTab);
            app.ENTERPASSWORDTextArea.Visible = 'off';
            app.LOGINButton = uibutton(app.LOGINTab, 'push');
            app.LOGINButton.ButtonPushedFcn = createCallbackFcn(app, @loginCallback, true);
            app.LOGINButton.Visible = 'off';
            app.LOGINButton.Text = 'LOGIN';
            app.ENTERPASSWORDLabel = uilabel(app.LOGINTab);
            app.ENTERPASSWORDLabel.Visible = 'off';
            app.ENTERPASSWORDLabel.Text = 'ENTER PASSWORD';
            app.ENTEREMAILTextArea = uitextarea(app.LOGINTab);
            app.ENTEREMAILTextArea.Visible = 'off';
            app.ENTEREMAILLabel = uilabel(app.LOGINTab);
            app.ENTEREMAILLabel.Visible = 'off';
            app.ENTEREMAILLabel.Text = 'ENTER EMAIL';
            app.login_status = uilabel(app.LOGINTab);
            app.login_status.Visible = 'off';
            app.login_status.Text = '';
            app.REGISTERTab = uitab(app.TabGroup);
            app.REGISTERTab.Title = 'REGISTER';
            app.USERNAMETextAreaLabel = uilabel(app.REGISTERTab);
            app.USERNAMETextAreaLabel.Text = 'USERNAME';
            app.USERNAMETextArea = uitextarea(app.REGISTERTab);
            app.PASSWORDTextAreaLabel = uilabel(app.REGISTERTab);
            app.PASSWORDTextAreaLabel.Text = 'PASSWORD';
            app.PASSWORDTextArea = uitextarea(app.REGISTERTab);
            app.REGISTERButton = uibutton(app.REGISTERTab, 'push');
            app.REGISTERButton.ButtonPushedFcn = createCallbackFcn(app, @registerCallback, true);
            app.REGISTERButton.Text = 'REGISTER';
            app.EMAILTextAreaLabel = uilabel(app.REGISTERTab);
            app.EMAILTextAreaLabel.Text = 'EMAIL';
            app.EMAILTextArea = uitextarea(app.REGISTERTab);
            app.SELECTTYPEButtonGroup = uibuttongroup(app.REGISTERTab);
            app.SELECTTYPEButtonGroup.Title = 'SELECT TYPE';
            app.TECHNICALButton = uiradiobutton(app.SELECTTYPEButtonGroup);
            app.TECHNICALButton.Text = 'TECHNICAL';
            app.TECHNICALButton.Value = true;
            app.MANAGERIALButton = uiradiobutton(app.SELECTTYPEButtonGroup);
            app.MANAGERIALButton.Text = 'MANAGERIAL';
            app.HRButton = uiradiobutton(app.SELECTTYPEButtonGroup);
            app.HRButton.Text = 'HR';
            app.display_registered = uilabel(app.REGISTERTab);
            app.display_registered.Visible = 'off';
            app.display_registered.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end